classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetESTOPButton       matlab.ui.control.Button
        LoadEnvironmentButton  matlab.ui.control.Button
        Drink1Button           matlab.ui.control.Button
        Drink2Button           matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Drink1Button
        function Drink1ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            
            % Run function code for drink 1
            
            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
        end

        % Button pushed function: Drink2Button
        function Drink2ButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            
            % Run function code for drink 2
            
            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
        end

        % Button pushed function: LoadEnvironmentButton
        function LoadEnvironmentButtonPushed(app, event)
            % Disable buttons to prevent multiple clicks
            app.Drink1Button.Enable = 'off';
            app.Drink2Button.Enable = 'off';
            app.LoadEnvironmentButton.Enable = 'off';
                    
            % Load environment

            % Re-enable buttons after function is complete
            app.Drink1Button.Enable = 'on';
            app.Drink2Button.Enable = 'on';
            app.ResetESTOPButton.Enable = 'on';
        end

        % Button pushed function: ResetESTOPButton
        function ResetESTOPButtonPushed(app, event)
            % code to reset robot 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Bar Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Drink2Button = uibutton(app.UIFigure, 'push');
            app.Drink2Button.ButtonPushedFcn = createCallbackFcn(app, @Drink2ButtonPushed, true);
            app.Drink2Button.Enable = 'off';
            app.Drink2Button.Text = 'Drink2';
            app.Drink1Button = uibutton(app.UIFigure, 'push');
            app.Drink1Button.ButtonPushedFcn = createCallbackFcn(app, @Drink1ButtonPushed, true);
            app.Drink1Button.Enable = 'off';
            app.Drink1Button.Text = 'Drink1';
            app.LoadEnvironmentButton = uibutton(app.UIFigure, 'push');
            app.LoadEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvironmentButtonPushed, true);
            app.LoadEnvironmentButton.Text = 'Load Environment';
            app.ResetESTOPButton = uibutton(app.UIFigure, 'push');
            app.ResetESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ResetESTOPButtonPushed, true);
            app.ResetESTOPButton.Enable = 'off';
            app.ResetESTOPButton.Text = 'Reset ESTOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end